classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        Panel               matlab.ui.container.Panel
        Label               matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        Label_2             matlab.ui.control.Label
        UIAxes_time1        matlab.ui.control.UIAxes
        UIAxes_freq1        matlab.ui.control.UIAxes
        Panel_2             matlab.ui.container.Panel
        DropDown_2Label     matlab.ui.control.Label
        DropDown_2          matlab.ui.control.DropDown
        UIAxes_time2        matlab.ui.control.UIAxes
        UIAxes_freq_2       matlab.ui.control.UIAxes
        FIRTab              matlab.ui.container.Tab
        ButtonGroup_2       matlab.ui.container.ButtonGroup
        Button_10           matlab.ui.control.RadioButton
        Button_11           matlab.ui.control.RadioButton
        Button_12           matlab.ui.control.RadioButton
        Spinner             matlab.ui.control.Spinner
        Label_3             matlab.ui.control.Label
        Knob                matlab.ui.control.Knob
        TextArea_3          matlab.ui.control.TextArea
        UIAxes              matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        Tab_2               matlab.ui.container.Tab
        Button              matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        ButtonGroup         matlab.ui.container.ButtonGroup
        Button_5            matlab.ui.control.RadioButton
        Button_6            matlab.ui.control.RadioButton
        Button_7            matlab.ui.control.RadioButton
        Button_8            matlab.ui.control.RadioButton
        TextArea            matlab.ui.control.TextArea
        TextArea_2          matlab.ui.control.TextArea
        Button_9            matlab.ui.control.Button
        EditField           matlab.ui.control.EditField
        EditField_2         matlab.ui.control.EditField
        Button_13           matlab.ui.control.Button
        UIAxes_time_origin  matlab.ui.control.UIAxes
        UIAxes__time2       matlab.ui.control.UIAxes
        UIAxes_frq2         matlab.ui.control.UIAxes
        UIAxes_freq_origin  matlab.ui.control.UIAxes
        Tab_3               matlab.ui.container.Tab
        Panel_3             matlab.ui.container.Panel
        Image               matlab.ui.control.Image
        Button_15           matlab.ui.control.Button
        EditField_3         matlab.ui.control.EditField
        Button_16           matlab.ui.control.Button
        Button_17           matlab.ui.control.Button
    end

    
    properties (Access = private)
        y=[0] % Description
        Fs=44100 % Description
        y_origin % Description
        im % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: DropDown, DropDown
        function DropDownOpening(app, event)
            ax = app.UIAxes_time1;
            bx_freq = app.UIAxes_freq1;
            value = app.DropDown.Value;
            
            switch value
                case '1'
                    
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil(1.8*pi/Bt);
                    
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',boxcar(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                    
                case '2'
                    
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil(6.1*pi/Bt);
                    
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',triang(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                case '3'
                    
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil(6.2*pi/Bt);
                    
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',hanning(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                case '4'
                    
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil(6.6*pi/Bt);
                    
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',hamming(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                    
                case '5'
                    
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil(11*pi/Bt);
                    
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',blackman(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                    
                case '6'
                    
                    rs = 40;
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil((rs-8)/2.285/Bt);
                    alph = 0.5842*(rs-21)^0.4+0.07886*(rs-21);
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',kaiser(N,alph));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
            end
        end

        % Callback function: DropDown_2, DropDown_2
        function DropDown_2ValueChanged(app, event)
            ax = app.UIAxes_time2;
            bx_freq = app.UIAxes_freq_2;
            value = app.DropDown_2.Value;
            
            switch value
                case '1'
                    
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil(1.8*pi/Bt);
                    
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',boxcar(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                case '2'
                    
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil(6.1*pi/Bt);
                    
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',triang(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                case '3'
                    
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil(6.2*pi/Bt);
                    
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',hanning(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                case '4'
                    
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil(6.6*pi/Bt);
                    
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',hamming(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                    
                case '5'
                    
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil(11*pi/Bt);
                    
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',blackman(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                    
                case '6'
                    
                    rs = 40;
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N0 = ceil((rs-8)/2.285/Bt);
                    alph = 0.5842*(rs-21)^0.4+0.07886*(rs-21);
                    N = N0 + mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',kaiser(N,alph));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
            end
        end

        % Callback function
        function SliderValueChanging(app, event)
            ax = app.UIAxes;
            bx_freq = app.UIAxes_2;
            changingValue = event.Value;
            button_value = app.ButtonGroup_2.SelectedObject;
            switch button_value
                case app.Button_10
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N =ceil(changingValue);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',boxcar(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                case app.Button_11
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N =ceil(changingValue);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',triang(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                case app.Button_12
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N =ceil(changingValue);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',hamming(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            ax = app.UIAxes_time_origin;
            bx = app.UIAxes_freq_origin;
            [filename, pathname] = uigetfile('*.wav', '选择音频文件');
            path = fullfile(pathname, filename);
            [app.y,app.Fs]=audioread(path);
            app.y = app.y(:,1);
            app.y_origin = app.y;
            t= (0:length(app.y)-1)/app.Fs;
            plot(ax,t,app.y);
            
            %获取频谱
            N=4096;
            f = (-N/2:N/2-1)/N*app.Fs;
            x = fft(app.y,N);
            x = fftshift(x);
            plot(bx,f,abs(x)/max(abs(x)));
            axis(bx,[-4000,4000,0,1]);
            %axis([]);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            sound(app.y,app.Fs);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            clear sound;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            noise_white = 0.05*randn(length(app.y),1);
            
            app.y  = app.y+noise_white;
            bx = app.UIAxes_freq_origin;
             N=4096;
            f = (-N/2:N/2-1)/N*app.Fs;
            x = fft(app.y,N);
            x = fftshift(x);
            plot(bx,f,abs(x)/max(abs(x)));
            axis(bx,[-4000,4000,0,1]);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            button_value = app.ButtonGroup.SelectedObject;
            ax = app.UIAxes__time2;
            bx=app.UIAxes_frq2;
            switch button_value
                case   app.Button_5
                    f_low = str2double(app.TextArea.Value);
                    f_high = str2double(app.TextArea_2.Value);
                    wp = f_high/app.Fs*2*pi;
                    ws = f_low/app.Fs*2*pi;
                    Bt =wp-ws;
                    N0 = ceil(6.2*pi/Bt);
                    N =N0 +mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',hanning(N));
                    %[h,w]= freqz(hn);
                    %plot(ax,w/2/pi*app.Fs,abs(h));
                    app.y = fftfilt(hn,app.y);
                    t= (0:length(app.y)-1)/app.Fs;
                    plot(ax,t,app.y);
                    
                    %获取频谱
                    N=4096;
                    f = (-N/2:N/2-1)/N*app.Fs;
                    x = fft(app.y,N);
                    x = fftshift(x);
                    plot(bx,f,abs(x)/max(abs(x)));
                    axis(bx,[-4000,4000,0,1]);
                case   app.Button_6
                    f_low = str2double(app.TextArea.Value);
                    f_high = str2double(app.TextArea_2.Value);
                    wp = f_high/app.Fs*2*pi;
                    ws = f_low/app.Fs*2*pi;
                    Bt =wp-ws;
                    N0 = ceil(6.2*pi/Bt);
                    N =N0 +mod(N0+1,2);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'high',hanning(N));
                    %[h,w]= freqz(hn);
                    %plot(bx,w/2/pi*app.Fs,abs(h));
                    app.y = fftfilt(hn,app.y);
                    t= (0:length(app.y)-1)/app.Fs;
                    plot(ax,t,app.y);
                    
                    %获取频谱
                    N=4096;
                    f = (-N/2:N/2-1)/N*app.Fs;
                    x = fft(app.y,N);
                    x = fftshift(x);
                    plot(bx,f,abs(x)/max(abs(x)));
                    axis(bx,[-4000,4000,0,1]);
                case   app.Button_7
                    f_low = str2double(app.TextArea.Value);
                    f_high = str2double(app.TextArea_2.Value);
                    wp = f_high/app.Fs*2*pi;
                    ws = f_low/app.Fs*2*pi;
                    Bt =50*2*pi/app.Fs;
                    N0 = ceil(6.2*pi/Bt);
                    N =N0 +mod(N0+1,2);
                    
                    hn = fir1(N-1,[(ws-Bt/2)/pi,(wp+Bt/2)/pi],hanning(N));
                    %s[h,w]= freqz(hn);
                    %plot(bx,w/2/pi*app.Fs,abs(h));
                    app.y = fftfilt(hn,app.y);
                    t= (0:length(app.y)-1)/app.Fs;
                    plot(ax,t,app.y);
                    
                    %获取频谱
                    N=4096;
                    f = (-N/2:N/2-1)/N*app.Fs;
                    x = fft(app.y,N);
                    x = fftshift(x);
                    plot(bx,f,abs(x)/max(abs(x)));
                    axis(bx,[-4000,4000,0,1]);
                case   app.Button_8
                    
                    f_low = str2double(app.TextArea.Value);
                    f_high = str2double(app.TextArea_2.Value);
                    wp = f_high/app.Fs*2*pi;
                    ws = f_low/app.Fs*2*pi;
                    Bt =50*2*pi/app.Fs;
                    N0 = ceil(6.2*pi/Bt);
                    N =N0 +mod(N0+1,2);
                    
                    hn = fir1(N-1,[(ws-Bt/2)/pi,(wp+Bt/2)/pi],'stop',hanning(N));
                    %[h,w]= freqz(hn);
                    %plot(bx,w/2/pi*app.Fs,abs(h));
                    app.y = fftfilt(hn,app.y);
                    t= (0:length(app.y)-1)/app.Fs;
                    plot(ax,t,app.y);
                    
                    %获取频谱
                    N=4096;
                    f = (-N/2:N/2-1)/N*app.Fs;
                    x = fft(app.y,N);
                    x = fftshift(x);
                    plot(bx,f,abs(x)/max(abs(x)));
                    axis(bx,[-4000,4000,0,1]);
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton
                case   app.Button_5
                    app.EditField.Value = "通带边界频率：";
                    app.EditField_2.Value = "阻带截止频率";
                case   app.Button_6
                    app.EditField.Value =    "阻带截止频率:";
                    app.EditField_2.Value = "通带边界频率";
                case   app.Button_7
                    app.EditField.Value =    "通带下截止频率:";
                    app.EditField_2.Value = "通带上截止频率：";
                case   app.Button_8
                    app.EditField.Value =    "阻带下截止频率:";
                    app.EditField_2.Value = "阻带上截止频率：";
            end
        end

        % Callback function: Knob, Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            ax = app.UIAxes;
            bx_freq = app.UIAxes_2;
            app.Spinner.Value = ceil(changingValue);
            button_value = app.ButtonGroup_2.SelectedObject;
            switch button_value
                case app.Button_10
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N =ceil(changingValue);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',boxcar(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                case app.Button_11
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N =ceil(changingValue);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',triang(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
                case app.Button_12
                    wp = pi/2;ws = pi/4;
                    Bt = wp-ws;
                    N =ceil(changingValue);
                    wc = (wp+ws)/2/pi;
                    hn = fir1(N-1,wc,'low',hamming(N));
                    stem(ax,hn,'filled');
                    N_fft = 1024;
                    Y = fft(hn,N_fft);
                    Y = 20*log10(abs(Y));
                    x = (0:N_fft-1)*2/N_fft;
                    
                    plot(bx_freq,x,Y);
            end
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            if changingValue>=10 &&changingValue<=100
                
                app.Knob.Value = event.Value;
                
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            changingValue = event.Value;
            if changingValue>=10 &&changingValue<=100
                
                app.Knob.Value = event.Value;
                
            end
        end

        % Callback function
        function UIAxes_freq_originButtonDown(app, event)
            
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.y = app.y_origin;
        end

        % Callback function
        function Button_14Pushed(app, event)
            
        end

        % Callback function
        function Button2Pushed(app, event)
         
        end

        % Button pushed function: Button_15
        function Button_14Pushed2(app, event)
            % 读取原图并显示
             [filename, pathname] = uigetfile('*.png', '选择图像文件');
            path = fullfile(pathname, filename);
            im = imread(path); %读入图片（三通道RGB图像）
            app.im = im;
            figure(3);
            imshow(im);
            title('原图');


        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            I=app.im;
            J1=imnoise(I, 'gaussian',0,0.02);
            J2=imnoise(I, 'salt & pepper',0.02);
            J3=imnoise(I, 'speckle',0.02);
            figure(1);
            subplot(2,2,1),imshow(I),title('原图像');
            subplot(2,2,2),imshow(J1),title('加高斯噪声');
            subplot(2,2,3),imshow(J2),title('加椒盐噪声');
            subplot(2,2,4),imshow(J3),title('加乘性噪声');
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            figure(2);
            %将图像转为二进制
            I1=app.im;                  %此处可以选择原始图像或者以上J1-J3加入不同噪声后的图像
            I1=rgb2gray(I1);
            subplot(221),imshow(I1);
            xlabel('（a）原始图像');
            
            %对图像进行傅里叶变换
            f=double(I1);
            g=fftshift(fft2(I1));
            F2=log(abs(g));
            subplot(222),imshow(F2,[],'InitialMagnification','fit');
            xlabel('(b)原始图像的傅里叶变换图像');
            
            % 用高斯高通滤波对图像的FFT后的图像进行处理
            [a,b]=size(g);
            a0=round(a/2);
            b0=round(b/2);
            d0=20;
            for i=1:a
                for j=1:b
                    dis=sqrt((i-a0)^2+(j-b0)^2);
                    if  dis==d0
                        h=0;
                    else
                        h=exp(-dis/d0);
                        h=1-h; %此处可以改变高通滤波器的阈值
                    end
                    result(i,j)=h*g(i,j);
                end
            end
            F3=log(abs(result));
            subplot(224),imshow(F3,[],'InitialMagnification','fit');
            xlabel('（d）高斯高通滤波后图像的傅里叶变换图像');
            
            
            %将经过高斯高通滤波后的FFT图像进行反变换即可得到最终图像
            result=ifftshift(result);
            result=ifft2(result);
            X2=uint8(real(result));
            subplot(223),imshow(X2);
            xlabel('(c)高斯高通滤波后的图像');
         
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '窗函数对比';
            app.Panel = uipanel(app.Tab);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '窗函数选择：';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'矩形窗', '三角窗', '汉宁窗', '哈明窗', '布莱克曼窗', '凯撒-贝塞尔窗'};
            app.DropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.Value = '1';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.UIAxes_time1 = uiaxes(app.Panel);
            title(app.UIAxes_time1, 'h(n)波形')
            xlabel(app.UIAxes_time1, 'n')
            ylabel(app.UIAxes_time1, {'h(n)'; ''})
            zlabel(app.UIAxes_time1, 'Z')
            app.UIAxes_freq1 = uiaxes(app.Panel);
            title(app.UIAxes_freq1, '损耗函数曲线')
            xlabel(app.UIAxes_freq1, 'ω/π')
            ylabel(app.UIAxes_freq1, '20log|Hg(ω)|')
            zlabel(app.UIAxes_freq1, 'Z')
            app.Panel_2 = uipanel(app.Tab);
            app.DropDown_2Label = uilabel(app.Panel_2);
            app.DropDown_2Label.Text = '窗函数选择：';
            app.DropDown_2 = uidropdown(app.Panel_2);
            app.DropDown_2.Items = {'矩形窗', '三角窗', '汉宁窗', '哈明窗', '布莱克曼窗', '凯撒-贝塞尔窗'};
            app.DropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.DropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '1';
            app.UIAxes_time2 = uiaxes(app.Panel_2);
            title(app.UIAxes_time2, 'h(n)波形')
            xlabel(app.UIAxes_time2, 'n')
            ylabel(app.UIAxes_time2, 'h(n)')
            zlabel(app.UIAxes_time2, 'Z')
            app.UIAxes_freq_2 = uiaxes(app.Panel_2);
            title(app.UIAxes_freq_2, '损耗函数曲线')
            xlabel(app.UIAxes_freq_2, {'ω/π'; ''})
            ylabel(app.UIAxes_freq_2, '20log|Hg(ω)|')
            zlabel(app.UIAxes_freq_2, 'Z')
            app.FIRTab = uitab(app.TabGroup);
            app.FIRTab.Title = 'FIR滤波器阶数可视化';
            app.ButtonGroup_2 = uibuttongroup(app.FIRTab);
            app.ButtonGroup_2.Title = '窗函数选择';
            app.Button_10 = uiradiobutton(app.ButtonGroup_2);
            app.Button_10.Text = '矩形窗';
            app.Button_10.Value = true;
            app.Button_11 = uiradiobutton(app.ButtonGroup_2);
            app.Button_11.Text = '三角窗';
            app.Button_12 = uiradiobutton(app.ButtonGroup_2);
            app.Button_12.Text = '汉明窗';
            app.Spinner = uispinner(app.FIRTab);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 10;
            app.Label_3 = uilabel(app.FIRTab);
            app.Label_3.Text = '滤波器阶数选择';
            app.Knob = uiknob(app.FIRTab, 'continuous');
            app.Knob.Limits = [10 100];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Knob.Value = 10;
            app.TextArea_3 = uitextarea(app.FIRTab);
            app.TextArea_3.Value = {'tips：'; '      改变数值后，右击转盘实现数值微调'};
            app.UIAxes = uiaxes(app.FIRTab);
            title(app.UIAxes, 'h(n)波形')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, {'h(n)'; ''})
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.FIRTab);
            title(app.UIAxes_2, '损耗函数曲线')
            xlabel(app.UIAxes_2, {'ω/π'; ''})
            ylabel(app.UIAxes_2, '20log|Hg(ω)|')
            zlabel(app.UIAxes_2, 'Z')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '语音信号处理';
            app.Button = uibutton(app.Tab_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '读取音频文件';
            app.Button_2 = uibutton(app.Tab_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '播放';
            app.Button_3 = uibutton(app.Tab_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '停止播放';
            app.Button_4 = uibutton(app.Tab_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '增加白噪声';
            app.ButtonGroup = uibuttongroup(app.Tab_2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '滤波器类型选择';
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '低通';
            app.Button_5.Value = true;
            app.Button_6 = uiradiobutton(app.ButtonGroup);
            app.Button_6.Text = '高通';
            app.Button_7 = uiradiobutton(app.ButtonGroup);
            app.Button_7.Text = '带通';
            app.Button_8 = uiradiobutton(app.ButtonGroup);
            app.Button_8.Text = '带阻';
            app.TextArea = uitextarea(app.Tab_2);
            app.TextArea_2 = uitextarea(app.Tab_2);
            app.Button_9 = uibutton(app.Tab_2, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '滤波处理';
            app.EditField = uieditfield(app.Tab_2, 'text');
            app.EditField.Value = '通带边界频率：';
            app.EditField_2 = uieditfield(app.Tab_2, 'text');
            app.EditField_2.Value = '阻带截止频率：';
            app.Button_13 = uibutton(app.Tab_2, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '恢复原始音频';
            app.UIAxes_time_origin = uiaxes(app.Tab_2);
            title(app.UIAxes_time_origin, '语音时域波形')
            xlabel(app.UIAxes_time_origin, '时间/s')
            ylabel(app.UIAxes_time_origin, 'Y')
            zlabel(app.UIAxes_time_origin, 'Z')
            app.UIAxes__time2 = uiaxes(app.Tab_2);
            title(app.UIAxes__time2, '滤波后时域波形')
            xlabel(app.UIAxes__time2, '时间/s')
            ylabel(app.UIAxes__time2, 'Y')
            zlabel(app.UIAxes__time2, 'Z')
            app.UIAxes_frq2 = uiaxes(app.Tab_2);
            title(app.UIAxes_frq2, '滤波后语音信号频域波形')
            xlabel(app.UIAxes_frq2, 'X')
            ylabel(app.UIAxes_frq2, 'Y')
            zlabel(app.UIAxes_frq2, 'Z')
            app.UIAxes_freq_origin = uiaxes(app.Tab_2);
            title(app.UIAxes_freq_origin, '语音信号频域波形')
            xlabel(app.UIAxes_freq_origin, '频率/Hz')
            ylabel(app.UIAxes_freq_origin, 'Y')
            zlabel(app.UIAxes_freq_origin, 'Z')
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '图像处理';
            app.Panel_3 = uipanel(app.Tab_3);
            app.Panel_3.Title = 'Panel';
            app.Image = uiimage(app.Panel_3);
            app.Image.ImageSource = 'back.png';
            app.Button_15 = uibutton(app.Panel_3, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed2, true);
            app.Button_15.Text = '添加图片';
            app.EditField_3 = uieditfield(app.Panel_3, 'text');
            app.EditField_3.Value = '图片加噪滤波';
            app.Button_16 = uibutton(app.Panel_3, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '添加噪声';
            app.Button_17 = uibutton(app.Panel_3, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '滤波处理';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end